classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        TempAxes                       matlab.ui.control.UIAxes
        CostAxes                       matlab.ui.control.UIAxes
        PowerAxes                      matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        StartButton                    matlab.ui.control.Button
        DurationSliderLabel            matlab.ui.control.Label
        DurationSlider                 matlab.ui.control.Slider
        OutdoortemperatureSliderLabel  matlab.ui.control.Label
        OutdoorTempSlider              matlab.ui.control.Slider
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CostAxes.Visible = 'off';
            app.PowerAxes.Visible = 'off';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            assignin('base','init_temp', app.OutdoorTempSlider.Value);
            simout = sim('model','StopTime',num2str(app.DurationSlider.Value));
            app.CostAxes.Visible = 'on';
            app.PowerAxes.Visible = 'on';
            plot(app.TempAxes,simout.temp);
            hold(app.TempAxes);
            plot(app.TempAxes, simout.temp2);
            plot(app.CostAxes,simout.cost);
            plot(app.PowerAxes, simout.power);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.TempAxes = uiaxes(app.UIFigure);
            title(app.TempAxes, 'Temperature')
            xlabel(app.TempAxes, 'X')
            ylabel(app.TempAxes, 'Y')
            app.CostAxes = uiaxes(app.UIFigure);
            title(app.CostAxes, 'Cost')
            xlabel(app.CostAxes, 'X')
            ylabel(app.CostAxes, 'Y')
            app.PowerAxes = uiaxes(app.UIFigure);
            title(app.PowerAxes, 'Power')
            xlabel(app.PowerAxes, 'X')
            ylabel(app.PowerAxes, 'Y')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DurationSliderLabel = uilabel(app.UIFigure);
            app.DurationSliderLabel.Text = 'Duration';
            app.DurationSlider = uislider(app.UIFigure);
            app.DurationSlider.Limits = [0 168];
            app.DurationSlider.MajorTicks = [0 24 48 72 96 120 144 168];
            app.DurationSlider.MinorTicks = [12 36 60 84 108 132 156];
            app.OutdoortemperatureSliderLabel = uilabel(app.UIFigure);
            app.OutdoortemperatureSliderLabel.Text = {'Outdoor'; 'temperature'};
            app.OutdoorTempSlider = uislider(app.UIFigure);
            app.OutdoorTempSlider.Limits = [-30 15];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end